classdef AppCalibrator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Calibrator20230105UIFigure  matlab.ui.Figure
        editSquareSize              matlab.ui.control.NumericEditField
        PlaneErrormLabel_8          matlab.ui.control.Label
        editLookAtZ                 matlab.ui.control.NumericEditField
        PlaneErrormLabel_7          matlab.ui.control.Label
        editLookAtY                 matlab.ui.control.NumericEditField
        PlaneErrormLabel_6          matlab.ui.control.Label
        editLookAtX                 matlab.ui.control.NumericEditField
        PlaneErrormLabel_5          matlab.ui.control.Label
        editCameraZ                 matlab.ui.control.NumericEditField
        PlaneErrormLabel_4          matlab.ui.control.Label
        editCameraY                 matlab.ui.control.NumericEditField
        PlaneErrormLabel_3          matlab.ui.control.Label
        editCameraX                 matlab.ui.control.NumericEditField
        PlaneErrormLabel_2          matlab.ui.control.Label
        editFrameMargins            matlab.ui.control.NumericEditField
        PlaneErrormLabel            matlab.ui.control.Label
        btnCalibrate                matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        Sensor1Tab                  matlab.ui.container.Tab
        lblMat1det                  matlab.ui.control.Label
        lblMat1err                  matlab.ui.control.Label
        Sensor1Mat                  matlab.ui.control.Table
        Sensor1Img                  matlab.ui.control.UIAxes
        Sensor2Tab                  matlab.ui.container.Tab
        lblMat2det                  matlab.ui.control.Label
        lblMat2err                  matlab.ui.control.Label
        Sensor2Mat                  matlab.ui.control.Table
        Sensor2Img                  matlab.ui.control.UIAxes
        Sensor3Tab                  matlab.ui.container.Tab
        lblMat3det                  matlab.ui.control.Label
        lblMat3err                  matlab.ui.control.Label
        Sensor3Mat                  matlab.ui.control.Table
        Sensor3Img                  matlab.ui.control.UIAxes
        SceneTab                    matlab.ui.container.Tab
        btnSaveConfiguration        matlab.ui.control.Button
        Axis                        matlab.ui.control.UIAxes
        editPlatformID              matlab.ui.control.EditField
        PlatformIDLabel             matlab.ui.control.Label
        btnScanBrowse               matlab.ui.control.Button
        editScanFileName            matlab.ui.control.EditField
        ScanfileEditFieldLabel      matlab.ui.control.Label
        btnConfigurationBrowse      matlab.ui.control.Button
        editConfigurationFileName   matlab.ui.control.EditField
        ConfigurationfileEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        lastDir
    end
    
    properties (Access = public)
        rawData % Description
        sensors
        transMats
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.lastDir = '';    

        end

        % Button pushed function: btnConfigurationBrowse
        function btnConfigurationBrowsePushed(app, event)
            [file,path] = uigetfile(strcat(app.lastDir,'*.db'));
            if (~isequal(file, 0))
                app.editConfigurationFileName.Value = strcat(path, file);
                app.lastDir = strcat(path,'\');
            end
        end

        % Button pushed function: btnScanBrowse
        function btnScanBrowsePushed(app, event)
            [file,path] = uigetfile(strcat(app.lastDir,'*.db'));
            if (~isequal(file, 0))
                app.editScanFileName.Value = strcat(path, file);
                app.lastDir = strcat(path,'\');            
            end
            
        end

        % Value changed function: editConfigurationFileName
        function editConfigurationFileNameValueChanged(app, event)
            app.btnImportData.Enable = isfile(app.editConfigurationFileName.Value) & isfile(app.editScanFileName.Value);
            drawnow;

        end

        % Value changed function: editScanFileName
        function editScanFileNameValueChanged(app, event)
            app.btnImportData.Enable = isfile(app.editConfigurationFileName.Value) & isfile(app.editScanFileName.Value);
            drawnow;

        end

        % Button pushed function: btnCalibrate
        function btnCalibrateButtonPushed(app, event)
             [code, app.sensors, app.transMats, app.rawData, platformData] = ReadFromSQL(app.editConfigurationFileName.Value, app.editScanFileName.Value, app.editPlatformID.Value);
             if (code == 0)
                sensors = table2array(cell2table(app.sensors));
                for i=1:length(sensors)
                    app.TabGroup.Children(i).Title =sensors(i);
                end
    
                [mats, images, walls] = Calibrate(app.editConfigurationFileName.Value, app.editScanFileName.Value, app.editPlatformID.Value, app, 0);
                app.Sensor1Mat.Data = mats{1};
                app.Sensor2Mat.Data = mats{2};
                app.Sensor3Mat.Data = mats{3};
                app.TabGroup.SelectedTab = app.TabGroup.Children(4);
                scannedModel = pccat([walls{1}, walls{2}, walls{3}]);
                 pcshow(scannedModel, 'Parent',app.Axis, 'BackgroundColor',app.Calibrator20230105UIFigure.Color);
                 app.transMats = mats;
             else
                 msgbox('Error on reading data files');
             end
        end

        % Button pushed function: btnSaveConfiguration
        function btnSaveConfigurationButtonPushed(app, event)
            SaveConfiguration(app.editConfigurationFileName.Value, app.editPlatformID.Value, app.transMats);
            %pcwrite(app.scannedModel,app.PLYfileName.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Calibrator20230105UIFigure = uifigure('Visible', 'off');
            app.Calibrator20230105UIFigure.Name = 'Calibrator 20230105';
            app.Calibrator20230105UIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.ConfigurationfileEditFieldLabel = uilabel(app.Calibrator20230105UIFigure);
            app.ConfigurationfileEditFieldLabel.Text = 'Configuration file:';
            app.editConfigurationFileName = uieditfield(app.Calibrator20230105UIFigure, 'text');
            app.editConfigurationFileName.ValueChangedFcn = createCallbackFcn(app, @editConfigurationFileNameValueChanged, true);
            app.editConfigurationFileName.Value = 'iMeasure.db';
            app.btnConfigurationBrowse = uibutton(app.Calibrator20230105UIFigure, 'push');
            app.btnConfigurationBrowse.ButtonPushedFcn = createCallbackFcn(app, @btnConfigurationBrowsePushed, true);
            app.btnConfigurationBrowse.Text = '...';
            app.ScanfileEditFieldLabel = uilabel(app.Calibrator20230105UIFigure);
            app.ScanfileEditFieldLabel.Text = 'Scan file:';
            app.editScanFileName = uieditfield(app.Calibrator20230105UIFigure, 'text');
            app.editScanFileName.ValueChangedFcn = createCallbackFcn(app, @editScanFileNameValueChanged, true);
            app.editScanFileName.Value = 'Calibration_scan.db';
            app.btnScanBrowse = uibutton(app.Calibrator20230105UIFigure, 'push');
            app.btnScanBrowse.ButtonPushedFcn = createCallbackFcn(app, @btnScanBrowsePushed, true);
            app.btnScanBrowse.Text = '...';
            app.PlatformIDLabel = uilabel(app.Calibrator20230105UIFigure);
            app.PlatformIDLabel.Text = 'Platform ID:';
            app.editPlatformID = uieditfield(app.Calibrator20230105UIFigure, 'text');
            app.editPlatformID.Value = 'H001';
            app.TabGroup = uitabgroup(app.Calibrator20230105UIFigure);
            app.Sensor1Tab = uitab(app.TabGroup);
            app.Sensor1Tab.Title = 'Sensor1';
            app.Sensor1Img = uiaxes(app.Sensor1Tab);
            app.Sensor1Img.XTick = [];
            app.Sensor1Img.YTick = [];
            app.Sensor1Mat = uitable(app.Sensor1Tab);
            app.Sensor1Mat.ColumnName = '';
            app.Sensor1Mat.RowName = {};
            app.lblMat1err = uilabel(app.Sensor1Tab);
            app.lblMat1err.Text = 'Error: N/A';
            app.lblMat1det = uilabel(app.Sensor1Tab);
            app.lblMat1det.Text = 'Determinant:';
            app.Sensor2Tab = uitab(app.TabGroup);
            app.Sensor2Tab.Title = 'Sensor2';
            app.Sensor2Img = uiaxes(app.Sensor2Tab);
            app.Sensor2Img.XTick = [];
            app.Sensor2Img.YTick = [];
            app.Sensor2Mat = uitable(app.Sensor2Tab);
            app.Sensor2Mat.ColumnName = '';
            app.Sensor2Mat.RowName = {};
            app.lblMat2err = uilabel(app.Sensor2Tab);
            app.lblMat2err.Text = 'Error: N/A';
            app.lblMat2det = uilabel(app.Sensor2Tab);
            app.lblMat2det.Text = 'Determinant:';
            app.Sensor3Tab = uitab(app.TabGroup);
            app.Sensor3Tab.Title = 'Sensor3';
            app.Sensor3Img = uiaxes(app.Sensor3Tab);
            app.Sensor3Img.XTick = [];
            app.Sensor3Img.YTick = [];
            app.Sensor3Mat = uitable(app.Sensor3Tab);
            app.Sensor3Mat.ColumnName = '';
            app.Sensor3Mat.RowName = {};
            app.lblMat3err = uilabel(app.Sensor3Tab);
            app.lblMat3err.Text = 'Error: N/A';
            app.lblMat3det = uilabel(app.Sensor3Tab);
            app.lblMat3det.Text = 'Determinant:';
            app.SceneTab = uitab(app.TabGroup);
            app.SceneTab.Title = 'Scene';
            app.Axis = uiaxes(app.SceneTab);
            xlabel(app.Axis, 'X')
            ylabel(app.Axis, 'Y')
            zlabel(app.Axis, 'Z')
            app.btnSaveConfiguration = uibutton(app.SceneTab, 'push');
            app.btnSaveConfiguration.ButtonPushedFcn = createCallbackFcn(app, @btnSaveConfigurationButtonPushed, true);
            app.btnSaveConfiguration.Text = 'Save Configuration';
            app.btnCalibrate = uibutton(app.Calibrator20230105UIFigure, 'push');
            app.btnCalibrate.ButtonPushedFcn = createCallbackFcn(app, @btnCalibrateButtonPushed, true);
            app.btnCalibrate.Text = 'Calibrate!';
            app.PlaneErrormLabel = uilabel(app.Calibrator20230105UIFigure);
            app.PlaneErrormLabel.Text = 'Frame margins (%)';
            app.editFrameMargins = uieditfield(app.Calibrator20230105UIFigure, 'numeric');
            app.editFrameMargins.Limits = [0 50];
            app.editFrameMargins.ValueDisplayFormat = '%2d%%';
            app.editFrameMargins.Value = 10;
            app.PlaneErrormLabel_2 = uilabel(app.Calibrator20230105UIFigure);
            app.PlaneErrormLabel_2.Text = 'Camera Position X (m):';
            app.editCameraX = uieditfield(app.Calibrator20230105UIFigure, 'numeric');
            app.editCameraX.Limits = [0 1.5];
            app.editCameraX.ValueDisplayFormat = '%1.3fm';
            app.editCameraX.Value = 0.75;
            app.PlaneErrormLabel_3 = uilabel(app.Calibrator20230105UIFigure);
            app.PlaneErrormLabel_3.Text = 'Camera Position Y (m):';
            app.editCameraY = uieditfield(app.Calibrator20230105UIFigure, 'numeric');
            app.editCameraY.Limits = [0 1.5];
            app.editCameraY.ValueDisplayFormat = '%1.3fm';
            app.editCameraY.Value = 0.75;
            app.PlaneErrormLabel_4 = uilabel(app.Calibrator20230105UIFigure);
            app.PlaneErrormLabel_4.Text = 'Camera Position Z (m):';
            app.editCameraZ = uieditfield(app.Calibrator20230105UIFigure, 'numeric');
            app.editCameraZ.Limits = [0 1];
            app.editCameraZ.ValueDisplayFormat = '%1.3fm';
            app.editCameraZ.Value = 0.5;
            app.PlaneErrormLabel_5 = uilabel(app.Calibrator20230105UIFigure);
            app.PlaneErrormLabel_5.Text = 'Look At X (m):';
            app.editLookAtX = uieditfield(app.Calibrator20230105UIFigure, 'numeric');
            app.editLookAtX.Limits = [0 1.5];
            app.editLookAtX.ValueDisplayFormat = '%1.3fm';
            app.editLookAtX.Value = 1.5;
            app.PlaneErrormLabel_6 = uilabel(app.Calibrator20230105UIFigure);
            app.PlaneErrormLabel_6.Text = 'Look At Y (m):';
            app.editLookAtY = uieditfield(app.Calibrator20230105UIFigure, 'numeric');
            app.editLookAtY.Limits = [0 1.5];
            app.editLookAtY.ValueDisplayFormat = '%1.3fm';
            app.editLookAtY.Value = 1.5;
            app.PlaneErrormLabel_7 = uilabel(app.Calibrator20230105UIFigure);
            app.PlaneErrormLabel_7.Text = 'Look At Z (m):';
            app.editLookAtZ = uieditfield(app.Calibrator20230105UIFigure, 'numeric');
            app.editLookAtZ.Limits = [0 1];
            app.editLookAtZ.ValueDisplayFormat = '%1.3fm';
            app.editLookAtZ.Value = 0.5;
            app.PlaneErrormLabel_8 = uilabel(app.Calibrator20230105UIFigure);
            app.PlaneErrormLabel_8.Text = 'Square Size (m):';
            app.editSquareSize = uieditfield(app.Calibrator20230105UIFigure, 'numeric');
            app.editSquareSize.Limits = [0 1];
            app.editSquareSize.ValueDisplayFormat = '%1.3fm';
            app.editSquareSize.Value = 0.1;
            % Show the figure after all components are created
            app.Calibrator20230105UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppCalibrator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Calibrator20230105UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Calibrator20230105UIFigure)
        end
    end
end