classdef CalibratorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibratorUIFigure      matlab.ui.Figure
        editOutputFileName      matlab.ui.control.EditField
        OutputfileLabel         matlab.ui.control.Label
        editSquareSize          matlab.ui.control.NumericEditField
        PlaneErrormLabel_8      matlab.ui.control.Label
        editLookAtZ             matlab.ui.control.NumericEditField
        PlaneErrormLabel_7      matlab.ui.control.Label
        editLookAtY             matlab.ui.control.NumericEditField
        PlaneErrormLabel_6      matlab.ui.control.Label
        editLookAtX             matlab.ui.control.NumericEditField
        PlaneErrormLabel_5      matlab.ui.control.Label
        editCameraZ             matlab.ui.control.NumericEditField
        PlaneErrormLabel_4      matlab.ui.control.Label
        editCameraY             matlab.ui.control.NumericEditField
        PlaneErrormLabel_3      matlab.ui.control.Label
        editCameraX             matlab.ui.control.NumericEditField
        PlaneErrormLabel_2      matlab.ui.control.Label
        editFrameMargins        matlab.ui.control.NumericEditField
        PlaneErrormLabel        matlab.ui.control.Label
        btnCalibrate            matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        Sensor1Tab              matlab.ui.container.Tab
        lblMat1det              matlab.ui.control.Label
        lblMat1err              matlab.ui.control.Label
        Sensor1Mat              matlab.ui.control.Table
        Sensor1Img              matlab.ui.control.UIAxes
        Sensor2Tab              matlab.ui.container.Tab
        lblMat2det              matlab.ui.control.Label
        lblMat2err              matlab.ui.control.Label
        Sensor2Mat              matlab.ui.control.Table
        Sensor2Img              matlab.ui.control.UIAxes
        Sensor3Tab              matlab.ui.container.Tab
        lblMat3det              matlab.ui.control.Label
        lblMat3err              matlab.ui.control.Label
        Sensor3Mat              matlab.ui.control.Table
        Sensor3Img              matlab.ui.control.UIAxes
        SceneTab                matlab.ui.container.Tab
        btnSaveConfiguration    matlab.ui.control.Button
        Axis                    matlab.ui.control.UIAxes
        btnScanBrowse           matlab.ui.control.Button
        editScanFileName        matlab.ui.control.EditField
        ScanfileEditFieldLabel  matlab.ui.control.Label
        btnParametersBrowse     matlab.ui.control.Button
        editParametersFileName  matlab.ui.control.EditField
        ParametersfileLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        lastDir
        verbose
    end
    
    properties (Access = public)
        rawData % Description
        sensors
        transMats
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputFileName, parametersFileName, outputFileName, verbose)
            disp ('Init');
            fprintf("CalibratorApp/ Input file: %s\n", inputFileName);
            fprintf("CalibratorApp/ Parameters file: %s\n", parametersFileName);
            fprintf("CalibratorApp/ MatricesFile: %s\n", outputFileName);
            fprintf("CalibratorApp/ verbose: %s\n\n", verbose);
        app.lastDir = '';    
        app.verbose = verbose;
        app.editScanFileName.Value = inputFileName;
        app.editParametersFileName.Value = parametersFileName;
        app.editOutputFileName.Value = outputFileName;

        end

        % Button pushed function: btnParametersBrowse
        function btnParametersBrowsePushed(app, event)
            [file,path] = uigetfile(strcat(app.lastDir,'*.txt'));
            if (~isequal(file, 0))
                app.editParametersFileName.Value = strcat(path, file);
                app.lastDir = strcat(path,'\');
            end
        end

        % Button pushed function: btnScanBrowse
        function btnScanBrowsePushed(app, event)
            [file,path] = uigetfile(strcat(app.lastDir,'*.db'));
            if (~isequal(file, 0))
                app.editScanFileName.Value = strcat(path, file);
                app.lastDir = strcat(path,'\');            
            end
            
        end

        % Value changed function: editParametersFileName
        function editParametersFileNameValueChanged(app, event)
            app.btnImportData.Enable = isfile(app.editParametersFileName.Value) & isfile(app.editScanFileName.Value);
            drawnow;

        end

        % Value changed function: editScanFileName
        function editScanFileNameValueChanged(app, event)
            app.btnImportData.Enable = isfile(app.editParametersFileName.Value) & isfile(app.editScanFileName.Value);
            drawnow;

        end

        % Button pushed function: btnCalibrate
        function btnCalibrateButtonPushed(app, event)
    
            [code, mats, walls] = DoCalibrate(app.editScanFileName.Value, app.editParametersFileName.Value, app.editOutputFileName.Value, 0, app);
            if (code == 0)
                app.Sensor1Mat.Data = mats{1};
                app.Sensor2Mat.Data = mats{2};
                app.Sensor3Mat.Data = mats{3};
                app.TabGroup.SelectedTab = app.TabGroup.Children(4);
                scannedModel = pccat([walls{1}, walls{2}, walls{3}]);
                 pcshow(scannedModel, 'Parent',app.Axis, 'BackgroundColor',app.CalibratorUIFigure.Color);
                 app.transMats = mats;
             else
                 msgbox('Error on reading data files');
             end
        end

        % Button pushed function: btnSaveConfiguration
        function btnSaveConfigurationButtonPushed(app, event)
            SaveConfiguration(app.editParametersFileName.Value, app.editPlatformID.Value, app.transMats);
            %pcwrite(app.scannedModel,app.PLYfileName.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CalibratorUIFigure = uifigure('Visible', 'off');
            app.CalibratorUIFigure.Name = 'Calibrator 20230115';
            app.CalibratorUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.ParametersfileLabel = uilabel(app.CalibratorUIFigure);
            app.ParametersfileLabel.Text = 'Parameters file:';
            app.editParametersFileName = uieditfield(app.CalibratorUIFigure, 'text');
            app.editParametersFileName.ValueChangedFcn = createCallbackFcn(app, @editParametersFileNameValueChanged, true);
            app.editParametersFileName.Value = 'params.txt';
            app.btnParametersBrowse = uibutton(app.CalibratorUIFigure, 'push');
            app.btnParametersBrowse.ButtonPushedFcn = createCallbackFcn(app, @btnParametersBrowsePushed, true);
            app.btnParametersBrowse.Text = '...';
            app.ScanfileEditFieldLabel = uilabel(app.CalibratorUIFigure);
            app.ScanfileEditFieldLabel.Text = 'Scan file:';
            app.editScanFileName = uieditfield(app.CalibratorUIFigure, 'text');
            app.editScanFileName.ValueChangedFcn = createCallbackFcn(app, @editScanFileNameValueChanged, true);
            app.editScanFileName.Value = 'Calibration_scan.db';
            app.btnScanBrowse = uibutton(app.CalibratorUIFigure, 'push');
            app.btnScanBrowse.ButtonPushedFcn = createCallbackFcn(app, @btnScanBrowsePushed, true);
            app.btnScanBrowse.Text = '...';
            app.TabGroup = uitabgroup(app.CalibratorUIFigure);
            app.Sensor1Tab = uitab(app.TabGroup);
            app.Sensor1Tab.Title = 'Sensor1';
            app.Sensor1Img = uiaxes(app.Sensor1Tab);
            app.Sensor1Img.XTick = [];
            app.Sensor1Img.YTick = [];
            app.Sensor1Mat = uitable(app.Sensor1Tab);
            app.Sensor1Mat.ColumnName = '';
            app.Sensor1Mat.RowName = {};
            app.lblMat1err = uilabel(app.Sensor1Tab);
            app.lblMat1err.Text = 'Error: N/A';
            app.lblMat1det = uilabel(app.Sensor1Tab);
            app.lblMat1det.Text = 'Determinant:';
            app.Sensor2Tab = uitab(app.TabGroup);
            app.Sensor2Tab.Title = 'Sensor2';
            app.Sensor2Img = uiaxes(app.Sensor2Tab);
            app.Sensor2Img.XTick = [];
            app.Sensor2Img.YTick = [];
            app.Sensor2Mat = uitable(app.Sensor2Tab);
            app.Sensor2Mat.ColumnName = '';
            app.Sensor2Mat.RowName = {};
            app.lblMat2err = uilabel(app.Sensor2Tab);
            app.lblMat2err.Text = 'Error: N/A';
            app.lblMat2det = uilabel(app.Sensor2Tab);
            app.lblMat2det.Text = 'Determinant:';
            app.Sensor3Tab = uitab(app.TabGroup);
            app.Sensor3Tab.Title = 'Sensor3';
            app.Sensor3Img = uiaxes(app.Sensor3Tab);
            app.Sensor3Img.XTick = [];
            app.Sensor3Img.YTick = [];
            app.Sensor3Mat = uitable(app.Sensor3Tab);
            app.Sensor3Mat.ColumnName = '';
            app.Sensor3Mat.RowName = {};
            app.lblMat3err = uilabel(app.Sensor3Tab);
            app.lblMat3err.Text = 'Error: N/A';
            app.lblMat3det = uilabel(app.Sensor3Tab);
            app.lblMat3det.Text = 'Determinant:';
            app.SceneTab = uitab(app.TabGroup);
            app.SceneTab.Title = 'Scene';
            app.Axis = uiaxes(app.SceneTab);
            xlabel(app.Axis, 'X')
            ylabel(app.Axis, 'Y')
            zlabel(app.Axis, 'Z')
            app.btnSaveConfiguration = uibutton(app.SceneTab, 'push');
            app.btnSaveConfiguration.ButtonPushedFcn = createCallbackFcn(app, @btnSaveConfigurationButtonPushed, true);
            app.btnSaveConfiguration.Enable = 'off';
            app.btnSaveConfiguration.Text = 'Save Configuration';
            app.btnCalibrate = uibutton(app.CalibratorUIFigure, 'push');
            app.btnCalibrate.ButtonPushedFcn = createCallbackFcn(app, @btnCalibrateButtonPushed, true);
            app.btnCalibrate.Text = 'Calibrate!';
            app.PlaneErrormLabel = uilabel(app.CalibratorUIFigure);
            app.PlaneErrormLabel.Enable = 'off';
            app.PlaneErrormLabel.Text = 'Frame margins (%)';
            app.editFrameMargins = uieditfield(app.CalibratorUIFigure, 'numeric');
            app.editFrameMargins.Limits = [0 50];
            app.editFrameMargins.ValueDisplayFormat = '%2d%%';
            app.editFrameMargins.Enable = 'off';
            app.editFrameMargins.Value = 10;
            app.PlaneErrormLabel_2 = uilabel(app.CalibratorUIFigure);
            app.PlaneErrormLabel_2.Enable = 'off';
            app.PlaneErrormLabel_2.Text = 'Camera Position X (m):';
            app.editCameraX = uieditfield(app.CalibratorUIFigure, 'numeric');
            app.editCameraX.Limits = [0 1.5];
            app.editCameraX.ValueDisplayFormat = '%1.3fm';
            app.editCameraX.Enable = 'off';
            app.editCameraX.Value = 0.75;
            app.PlaneErrormLabel_3 = uilabel(app.CalibratorUIFigure);
            app.PlaneErrormLabel_3.Enable = 'off';
            app.PlaneErrormLabel_3.Text = 'Camera Position Y (m):';
            app.editCameraY = uieditfield(app.CalibratorUIFigure, 'numeric');
            app.editCameraY.Limits = [0 1.5];
            app.editCameraY.ValueDisplayFormat = '%1.3fm';
            app.editCameraY.Enable = 'off';
            app.editCameraY.Value = 0.75;
            app.PlaneErrormLabel_4 = uilabel(app.CalibratorUIFigure);
            app.PlaneErrormLabel_4.Enable = 'off';
            app.PlaneErrormLabel_4.Text = 'Camera Position Z (m):';
            app.editCameraZ = uieditfield(app.CalibratorUIFigure, 'numeric');
            app.editCameraZ.Limits = [0 1];
            app.editCameraZ.ValueDisplayFormat = '%1.3fm';
            app.editCameraZ.Enable = 'off';
            app.editCameraZ.Value = 0.5;
            app.PlaneErrormLabel_5 = uilabel(app.CalibratorUIFigure);
            app.PlaneErrormLabel_5.Enable = 'off';
            app.PlaneErrormLabel_5.Text = 'Look At X (m):';
            app.editLookAtX = uieditfield(app.CalibratorUIFigure, 'numeric');
            app.editLookAtX.Limits = [0 1.5];
            app.editLookAtX.ValueDisplayFormat = '%1.3fm';
            app.editLookAtX.Enable = 'off';
            app.editLookAtX.Value = 1.5;
            app.PlaneErrormLabel_6 = uilabel(app.CalibratorUIFigure);
            app.PlaneErrormLabel_6.Enable = 'off';
            app.PlaneErrormLabel_6.Text = 'Look At Y (m):';
            app.editLookAtY = uieditfield(app.CalibratorUIFigure, 'numeric');
            app.editLookAtY.Limits = [0 1.5];
            app.editLookAtY.ValueDisplayFormat = '%1.3fm';
            app.editLookAtY.Enable = 'off';
            app.editLookAtY.Value = 1.5;
            app.PlaneErrormLabel_7 = uilabel(app.CalibratorUIFigure);
            app.PlaneErrormLabel_7.Enable = 'off';
            app.PlaneErrormLabel_7.Text = 'Look At Z (m):';
            app.editLookAtZ = uieditfield(app.CalibratorUIFigure, 'numeric');
            app.editLookAtZ.Limits = [0 1];
            app.editLookAtZ.ValueDisplayFormat = '%1.3fm';
            app.editLookAtZ.Enable = 'off';
            app.editLookAtZ.Value = 0.5;
            app.PlaneErrormLabel_8 = uilabel(app.CalibratorUIFigure);
            app.PlaneErrormLabel_8.Enable = 'off';
            app.PlaneErrormLabel_8.Text = 'Square Size (m):';
            app.editSquareSize = uieditfield(app.CalibratorUIFigure, 'numeric');
            app.editSquareSize.Limits = [0 1];
            app.editSquareSize.ValueDisplayFormat = '%1.3fm';
            app.editSquareSize.Enable = 'off';
            app.editSquareSize.Value = 0.1;
            app.OutputfileLabel = uilabel(app.CalibratorUIFigure);
            app.OutputfileLabel.Text = 'Output file:';
            app.editOutputFileName = uieditfield(app.CalibratorUIFigure, 'text');
            app.editOutputFileName.Value = 'Calibrated.txt';
            % Show the figure after all components are created
            app.CalibratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalibratorApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CalibratorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CalibratorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibratorUIFigure)
        end
    end
end