classdef Scan2PLYApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SannerUIFigure          matlab.ui.Figure
        ParametersfileLabel     matlab.ui.control.Label
        btnProcess              matlab.ui.control.Button
        Export                  matlab.ui.container.Panel
        btnExportSection        matlab.ui.control.Button
        SectionH                matlab.ui.control.NumericEditField
        ICPgridsizemLabel_3     matlab.ui.control.Label
        DXFfileName             matlab.ui.control.EditField
        ExporttoPLYfileLabel_2  matlab.ui.control.Label
        btnExportPLY            matlab.ui.control.Button
        PLYfileName             matlab.ui.control.EditField
        ExporttoPLYfileLabel    matlab.ui.control.Label
        Frames                  matlab.ui.container.Panel
        cbAlign                 matlab.ui.control.CheckBox
        editPlanePoints         matlab.ui.control.NumericEditField
        PlaneErrormLabel_3      matlab.ui.control.Label
        editFrameMargins        matlab.ui.control.NumericEditField
        PlaneErrormLabel_2      matlab.ui.control.Label
        editPointsTolerance     matlab.ui.control.NumericEditField
        ICPgridsizemLabel_2     matlab.ui.control.Label
        editICPgrid             matlab.ui.control.NumericEditField
        ICPgridsizemLabel       matlab.ui.control.Label
        editPlaneError          matlab.ui.control.NumericEditField
        PlaneErrormLabel        matlab.ui.control.Label
        btnProcessFrames        matlab.ui.control.Button
        FrameGauge              matlab.ui.control.LinearGauge
        spnToFrame              matlab.ui.control.Spinner
        FromLabel_2             matlab.ui.control.Label
        spnFromFrame            matlab.ui.control.Spinner
        FromLabel               matlab.ui.control.Label
        ScanPanel               matlab.ui.container.Panel
        DisplayGauge            matlab.ui.control.LinearGauge
        lblNumberOfSensors      matlab.ui.control.Label
        btnBrowseScanfile       matlab.ui.control.Button
        btnBrowseParamsFile     matlab.ui.control.Button
        editParamsFileName      matlab.ui.control.EditField
        editScanFileName        matlab.ui.control.EditField
        ScanfileEditFieldLabel  matlab.ui.control.Label
        Sensor4Axis             matlab.ui.control.UIAxes
        Sensor3Axis             matlab.ui.control.UIAxes
        Sensor2Axis             matlab.ui.control.UIAxes
        Sensor1Axis             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cloudViewAx
        scannedModel
        inputFileName = 'scan.db';
        paramsFileName =  'params.txt';
        outputFileName = 'scan.ply';
        lastDir
        verbose
    end
    
    properties (Access = public)
        rawData % Description
        sensors
        transMats
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputFileName, paramsFileName, outputFileName, verbose)
            app.btnProcess.Enable = 'on';
            disp ('Init');
            fprintf("Scan2PLYapp/ Input file: %s\n", inputFileName);
            fprintf("Scan2PLYapp/ Parameters file: %s\n", paramsFileName);
            fprintf("Scan2PLYapp/ Output file: %s\n", outputFileName);
            fprintf("Scan2PLYapp/ verbose: %s\n\n", verbose);
            app.verbose = verbose;
            app.editScanFileName.Value = inputFileName;
            app.editParamsFileName.Value = paramsFileName;
            app.PLYfileName.Value = outputFileName;

            app.cloudViewAx = axes(app.ScanPanel,'Units','Pixels', 'Color',app.SannerUIFigure.Color);
            set(app.Sensor1Axis,'xtick',[], 'ytick',[],'Color', 'black');
            set(app.Sensor2Axis,'xtick',[], 'ytick',[],'Color', 'black');
            set(app.Sensor3Axis,'xtick',[], 'ytick',[],'Color', 'black');
            set(app.Sensor4Axis,'xtick',[], 'ytick',[],'Color', 'black');
            app.lastDir = '';


        end

        % Callback function
        function btnBrowseConfigFilePushed(app, event)

        end

        % Button pushed function: btnBrowseScanfile
        function btnBrowseScanfilePushed(app, event)
            [file,path] = uigetfile(strcat(app.lastDir,'*.db'));
            if (~isequal(file, 0))
                app.editScanFileName.Value = strcat(path, file);
                app.lastDir = strcat(path,'\');
            end
            
        end

        % Value changing function: spnFromFrame
        function spnFromFrameValueChanging(app, event)
            changingValue = event.Value;
            app.btnProcessFrames.Text = sprintf('Process frames %d >> %d', changingValue, app.spnToFrame.Value);
            app.spnToFrame.Limits = [changingValue, app.spnToFrame.Value];
            app.FrameGauge.Limits = [changingValue, app.spnToFrame.Value];
            app.FrameGauge.MajorTicks = app.FrameGauge.Limits(1):app.FrameGauge.Limits(2);

        end

        % Value changing function: spnToFrame
        function spnToFrameValueChanging(app, event)
            changingValue = event.Value;
            app.btnProcessFrames.Text = sprintf('Process frames %d >> %d', app.spnFromFrame.Value, changingValue);
            app.spnFromFrame.Limits = [app.spnFromFrame.Value, changingValue];
            app.FrameGauge.Limits = [app.spnFromFrame.Value, changingValue];
            app.FrameGauge.MajorTicks = app.FrameGauge.Limits(1):app.FrameGauge.Limits(2);
            
        end

        % Button pushed function: btnProcessFrames
        function btnProcessFramesButtonPushed(app, event)
            set(app.SannerUIFigure, 'Pointer','watch');
            drawnow();
            Axes = {app.Sensor1Axis, app.Sensor2Axis, app.Sensor3Axis, app.Sensor4Axis};
            params.fromFrame = app.spnFromFrame.Value;
            params.toFrame = app.spnToFrame.Value;
            params.PlaneError = app.editPlaneError.Value;
            params.ICPgrid = app.editICPgrid.Value;
            params.PointsTolerance = app.editPointsTolerance.Value;
            params.Margins = app.editFrameMargins.Value / 100;
            params.PlanePoints = app.editPlanePoints.Value;
            params.AlignModel = app.cbAlign.Value;
            WriteScan2PLYparams(params, app.editParamsFileName.Value);
            app.scannedModel = DoScan2PLY(app.editScanFileName.Value, app.editParamsFileName.Value, app.PLYfileName.Value, Axes, app.FrameGauge, app.DisplayGauge, 1);
            numVerticesString = num2str(size(app.scannedModel.Location,1));
            FIN = length(numVerticesString);
            for i = FIN-2:-3:2
               numVerticesString(i+1:end+1) = numVerticesString(i:end);
               numVerticesString(i) = ',';
            end
            cla(app.cloudViewAx);
            app.lblNumberOfSensors.Text = sprintf('Number of Sensors: %d    Points: %s', length(app.sensors), numVerticesString);
            if (app.cbAlign.Value > 0)
                app.scannedModel = AlignCloudToAxes(app.scannedModel, 0);
            end
            pcshow(app.scannedModel, 'Parent',app.cloudViewAx, 'BackgroundColor',app.SannerUIFigure.Color);
            set(app.SannerUIFigure, 'Pointer','arrow');
            drawnow();
            
        end

        % Button pushed function: btnExportPLY
        function btnExportPLYButtonPushed(app, event)
            pcwrite(app.scannedModel,app.PLYfileName.Value);
        end

        % Button pushed function: btnExportSection
        function btnExportSectionButtonPushed(app, event)
            tolerance = 1.00;
             [poly] = sliceDXF(app.scannedModel, app.SectionH.Value / 100, tolerance, app.DXFfileName.Value);
            hold(app.cloudViewAx,'on');
            if (size(poly,1) > 0)
                plot3(app.cloudViewAx, poly(:,1),poly(:,2), poly(:,3),'y','linewidth',2);
            end
            drawnow;

        end

        % Button pushed function: btnProcess
        function btnProcessButtonPushed(app, event)
            [code, ~, app.sensors, ~,  app.transMats, app.rawData] = ReadFromSQLscan(app.editScanFileName.Value);
            if (code == 0)
                SENSORS = length(app.sensors);
                FRAMES = size(app.rawData{1},1);
                app.lblNumberOfSensors.Text = sprintf('Number of Sensors: %d', SENSORS);
                app.spnFromFrame.Limits = [1 FRAMES];
                app.spnToFrame.Limits = [1 FRAMES];
                app.spnToFrame.Value = FRAMES;
                app.FrameGauge.Limits = [0 FRAMES];
                app.FrameGauge.MajorTicks = app.FrameGauge.Limits(1):app.FrameGauge.Limits(2);
                app.btnProcessFrames.Text = sprintf('Process frames %d >> %d', app.spnFromFrame.Value, app.spnToFrame.Value);
                params = ReadScan2PLYparams(app.editParamsFileName.Value);
                app.editFrameMargins.Value = params.Margins*100;
                app.editPlaneError.Value = params.PlaneError;
                app.editPlanePoints.Value = params.PlanePoints;
                app.editICPgrid.Value = params.ICPgrid;
                app.editPointsTolerance.Value = params.PointsTolerance;

            else
                                 msgbox('Error on reading data files');
            end


        end

        % Button pushed function: btnBrowseParamsFile
        function btnBrowseParamsFileButtonPushed(app, event)
            [file,path] = uigetfile(strcat(app.lastDir,'*.txt'));
            if (~isequal(file, 0))
                app.editParamsFileName.Value = strcat(path, file);
                app.lastDir = strcat(path,'\');
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SannerUIFigure = uifigure('Visible', 'off');
            app.SannerUIFigure.Name = 'Sanner 20230115';
            app.Sensor1Axis = uiaxes(app.SannerUIFigure);
            title(app.Sensor1Axis, 'Sensor I')
            app.Sensor1Axis.XTick = [0 0.5 1];
            app.Sensor1Axis.YTick = [0 0.5 1];
            app.Sensor1Axis.YTickLabel = {'0'; '0.5'; '1'};
            app.Sensor2Axis = uiaxes(app.SannerUIFigure);
            title(app.Sensor2Axis, 'Sensor II')
            app.Sensor3Axis = uiaxes(app.SannerUIFigure);
            title(app.Sensor3Axis, 'Sensor III')
            app.Sensor4Axis = uiaxes(app.SannerUIFigure);
            title(app.Sensor4Axis, 'Sensor IV')
            app.ScanfileEditFieldLabel = uilabel(app.SannerUIFigure);
            app.ScanfileEditFieldLabel.Text = 'Scan file:';
            app.editScanFileName = uieditfield(app.SannerUIFigure, 'text');
            app.editParamsFileName = uieditfield(app.SannerUIFigure, 'text');
            app.editParamsFileName.Value = 'params.txt';
            app.btnBrowseParamsFile = uibutton(app.SannerUIFigure, 'push');
            app.btnBrowseParamsFile.ButtonPushedFcn = createCallbackFcn(app, @btnBrowseParamsFileButtonPushed, true);
            app.btnBrowseParamsFile.Text = '...';
            app.btnBrowseScanfile = uibutton(app.SannerUIFigure, 'push');
            app.btnBrowseScanfile.ButtonPushedFcn = createCallbackFcn(app, @btnBrowseScanfilePushed, true);
            app.btnBrowseScanfile.Text = '...';
            app.lblNumberOfSensors = uilabel(app.SannerUIFigure);
            app.lblNumberOfSensors.Text = 'Number of Sensors:';
            app.ScanPanel = uipanel(app.SannerUIFigure);
            app.ScanPanel.Title = 'Scan';
            app.DisplayGauge = uigauge(app.ScanPanel, 'linear');
            app.DisplayGauge.Limits = [0 1];
            app.DisplayGauge.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.DisplayGauge.MajorTickLabels = {''};
            app.DisplayGauge.MinorTicks = [];
            app.DisplayGauge.Visible = 'off';
            app.DisplayGauge.Value = 1;
            app.Frames = uipanel(app.SannerUIFigure);
            app.Frames.Title = 'Frames';
            app.FromLabel = uilabel(app.Frames);
            app.FromLabel.Text = 'From:';
            app.spnFromFrame = uispinner(app.Frames);
            app.spnFromFrame.ValueChangingFcn = createCallbackFcn(app, @spnFromFrameValueChanging, true);
            app.FromLabel_2 = uilabel(app.Frames);
            app.FromLabel_2.Text = 'To:';
            app.spnToFrame = uispinner(app.Frames);
            app.spnToFrame.ValueChangingFcn = createCallbackFcn(app, @spnToFrameValueChanging, true);
            app.FrameGauge = uigauge(app.Frames, 'linear');
            app.FrameGauge.Limits = [0 1];
            app.FrameGauge.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.FrameGauge.MajorTickLabels = {''};
            app.FrameGauge.MinorTicks = [];
            app.btnProcessFrames = uibutton(app.Frames, 'push');
            app.btnProcessFrames.ButtonPushedFcn = createCallbackFcn(app, @btnProcessFramesButtonPushed, true);
            app.btnProcessFrames.Text = 'Process frames';
            app.PlaneErrormLabel = uilabel(app.Frames);
            app.PlaneErrormLabel.Text = 'Plane Error (m):';
            app.editPlaneError = uieditfield(app.Frames, 'numeric');
            app.editPlaneError.Limits = [0 0.5];
            app.editPlaneError.ValueDisplayFormat = '%1.3fm';
            app.editPlaneError.Value = 0.05;
            app.ICPgridsizemLabel = uilabel(app.Frames);
            app.ICPgridsizemLabel.Text = 'ICP grid size (m):';
            app.editICPgrid = uieditfield(app.Frames, 'numeric');
            app.editICPgrid.Limits = [0 0.5];
            app.editICPgrid.ValueDisplayFormat = '%1.3fm';
            app.editICPgrid.Value = 0.05;
            app.ICPgridsizemLabel_2 = uilabel(app.Frames);
            app.ICPgridsizemLabel_2.Text = 'Points tolerance (m):';
            app.editPointsTolerance = uieditfield(app.Frames, 'numeric');
            app.editPointsTolerance.Limits = [0 0.5];
            app.editPointsTolerance.ValueDisplayFormat = '%1.3fm';
            app.editPointsTolerance.Value = 0.001;
            app.PlaneErrormLabel_2 = uilabel(app.Frames);
            app.PlaneErrormLabel_2.Text = 'Frame margins (%)';
            app.editFrameMargins = uieditfield(app.Frames, 'numeric');
            app.editFrameMargins.Limits = [0 50];
            app.editFrameMargins.ValueDisplayFormat = '%2d%%';
            app.editFrameMargins.Value = 10;
            app.PlaneErrormLabel_3 = uilabel(app.Frames);
            app.PlaneErrormLabel_3.Text = 'By # points:';
            app.editPlanePoints = uieditfield(app.Frames, 'numeric');
            app.editPlanePoints.Limits = [1 100000];
            app.editPlanePoints.ValueDisplayFormat = '%5d';
            app.editPlanePoints.Value = 3000;
            app.cbAlign = uicheckbox(app.Frames);
            app.cbAlign.Text = 'Align Model';
            app.Export = uipanel(app.SannerUIFigure);
            app.Export.Title = 'Export';
            app.ExporttoPLYfileLabel = uilabel(app.Export);
            app.ExporttoPLYfileLabel.Text = 'PLY file:';
            app.PLYfileName = uieditfield(app.Export, 'text');
            app.PLYfileName.Value = 'scan.ply';
            app.btnExportPLY = uibutton(app.Export, 'push');
            app.btnExportPLY.ButtonPushedFcn = createCallbackFcn(app, @btnExportPLYButtonPushed, true);
            app.btnExportPLY.Text = 'Go!';
            app.ExporttoPLYfileLabel_2 = uilabel(app.Export);
            app.ExporttoPLYfileLabel_2.Text = 'DXF section:';
            app.DXFfileName = uieditfield(app.Export, 'text');
            app.DXFfileName.Value = 'section.dxf';
            app.ICPgridsizemLabel_3 = uilabel(app.Export);
            app.ICPgridsizemLabel_3.Text = 'H:';
            app.SectionH = uieditfield(app.Export, 'numeric');
            app.SectionH.Limits = [-300 500];
            app.SectionH.ValueDisplayFormat = '%d cm';
            app.SectionH.Value = 100;
            app.btnExportSection = uibutton(app.Export, 'push');
            app.btnExportSection.ButtonPushedFcn = createCallbackFcn(app, @btnExportSectionButtonPushed, true);
            app.btnExportSection.Text = 'Go!';
            app.btnProcess = uibutton(app.SannerUIFigure, 'push');
            app.btnProcess.ButtonPushedFcn = createCallbackFcn(app, @btnProcessButtonPushed, true);
            app.btnProcess.Text = 'Process Scan!';
            app.ParametersfileLabel = uilabel(app.SannerUIFigure);
            app.ParametersfileLabel.Text = 'Parameters file:';
            % Show the figure after all components are created
            app.SannerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scan2PLYApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SannerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SannerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SannerUIFigure)
        end
    end
end